classdef gridspacingforyplus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateButton                 matlab.ui.control.Button
        GridSpacingForaGivenyLabel      matlab.ui.control.Label
        ReLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        rey                             matlab.ui.control.NumericEditField
        deltas                          matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        VelocitymsEditFieldLabel        matlab.ui.control.Label
        VelocitymsEditField             matlab.ui.control.NumericEditField
        ReferenceLengthmEditFieldLabel  matlab.ui.control.Label
        ReferenceLengthmEditField       matlab.ui.control.NumericEditField
        DynamicViscositykgmsEditFieldLabel  matlab.ui.control.Label
        DynamicViscositykgmsEditField   matlab.ui.control.NumericEditField
        DesiredyEditFieldLabel          matlab.ui.control.Label
        DesiredyEditField               matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        SkinFrictionCorrelationDropDownLabel  matlab.ui.control.Label
        SkinFrictionCorrelationDropDown  matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SkinFrictionCorrelationDropDown
        function SkinFrictionCorrelationDropDownValueChanged(app, event)
            format long 
            global eqn_cf wall_shear velocity_fric
            reynolds=app.Densitykgm3EditField.Value*app.VelocitymsEditField.Value*app.ReferenceLengthmEditField.Value/(app.DynamicViscositykgmsEditField.Value);
            value = app.SkinFrictionCorrelationDropDown.Value;
            switch value
                case 'Prandtl'
                    eqn_cf=0.074*reynolds^(-1.5);
                case 'Schlichting'
                    eqn_cf=(2*log10(reynolds)-0.65)^(-2.3);
                case 'Telfer'
                    eqn_cf=0.34*reynolds^(-1/3)+0.0012;
                case 'Prandtl-Schlichting'
                    eqn_cf=0.455*(log10(reynolds))^(-2.58);
                case '1/7 Power Law'
                    eqn_cf=0.0576*reynolds^(-1.5);
                case '1/7 Power Law with calibration'
                    eqn_cf=0.0592*reynolds^(-1.5);
            end
            wall_shear=(1/2)*app.Densitykgm3EditField.Value*app.VelocitymsEditField.Value^2*eqn_cf;
            velocity_fric=sqrt(wall_shear/app.Densitykgm3EditField.Value);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            SkinFrictionCorrelationDropDownValueChanged(app,event)
            format long
            global eqn_cf wall_shear velocity_fric
            if app.DynamicViscositykgmsEditField.Value==0
                app.Label.Text="Dynamic Viscosity can not be equal to zero!"
            elseif app.DesiredyEditField.Value==0
                app.Label.Text="y+ value can not be equal to zero!"
            else
                reynolds=app.Densitykgm3EditField.Value*app.VelocitymsEditField.Value*app.ReferenceLengthmEditField.Value/(app.DynamicViscositykgmsEditField.Value);
                wall_shear=(1/2)*app.Densitykgm3EditField.Value*app.VelocitymsEditField.Value^2*eqn_cf;
                velocity_fric=sqrt(wall_shear/app.Densitykgm3EditField.Value);
                yp=app.DesiredyEditField.Value*app.DynamicViscositykgmsEditField.Value/(app.Densitykgm3EditField.Value*velocity_fric);
                app.rey.Value=double(reynolds)
                app.deltas.Value=double(yp)    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GridSpacingForaGivenyLabel = uilabel(app.UIFigure);
            app.GridSpacingForaGivenyLabel.Text = 'Grid Spacing For a Given y+';
            app.ReLabel = uilabel(app.UIFigure);
            app.ReLabel.Text = 'Re';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 'Δs ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.rey = uieditfield(app.UIFigure, 'numeric');
            app.deltas = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity [m/s]';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReferenceLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.ReferenceLengthmEditFieldLabel.Text = 'Reference Length [m]';
            app.ReferenceLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DynamicViscositykgmsEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicViscositykgmsEditFieldLabel.Text = 'Dynamic Viscosity [kg/ms]';
            app.DynamicViscositykgmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredyEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredyEditFieldLabel.Text = 'Desired y+';
            app.DesiredyEditField = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density [kg/m3]';
            app.Densitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.SkinFrictionCorrelationDropDownLabel = uilabel(app.UIFigure);
            app.SkinFrictionCorrelationDropDownLabel.Text = 'Skin Friction Correlation';
            app.SkinFrictionCorrelationDropDown = uidropdown(app.UIFigure);
            app.SkinFrictionCorrelationDropDown.Items = {'Prandtl', 'Schlichting', 'Telfer', 'Prandtl-Schlichting', '1/7 Power Law', '1/7 Power Law with calibration'};
            app.SkinFrictionCorrelationDropDown.ValueChangedFcn = createCallbackFcn(app, @SkinFrictionCorrelationDropDownValueChanged, true);
            app.SkinFrictionCorrelationDropDown.Value = '1/7 Power Law';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gridspacingforyplus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end